classdef panels_ROI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ExperimentdirectoryLabel  matlab.ui.control.Label
        expDirEditField           matlab.ui.control.EditField
        imgTypeSwitch             matlab.ui.control.Switch
        loadRefImgButton          matlab.ui.control.Button
        TrialnumberLabel          matlab.ui.control.Label
        trialNumSpinner           matlab.ui.control.Spinner
        MaxintensityLabel         matlab.ui.control.Label
        maxIntensityEditField     matlab.ui.control.EditField
        ShowplanesLabel           matlab.ui.control.Label
        planesListBox             matlab.ui.control.ListBox
        updateDisplayButton       matlab.ui.control.Button
        drawButton                matlab.ui.control.Button
        onLabel                   matlab.ui.control.Label
        planeSelectDropdown       matlab.ui.control.DropDown
        andaddittoLabel           matlab.ui.control.Label
        selectedROIEditField      matlab.ui.control.EditField
        createROIButton           matlab.ui.control.Button
        deleteROIButton           matlab.ui.control.Button
        saveROIsButton            matlab.ui.control.Button
        plotPanel                 matlab.ui.container.Panel
        BrowseButton              matlab.ui.control.Button
        ROIsLabel                 matlab.ui.control.Label
        ROIsListBox               matlab.ui.control.ListBox
        msgLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        defaultExpDir = 'D:\Dropbox (HMS)\2P Data\Imaging Data';    % Starting directory for browse button
        expDir                                                      % The directory that the current refImages were loaded from
        refImages = [];                                             % Reference images for all trials
        trialNums = [];                                             % Vector of trial numbers for the loaded refImages
        refImageAxes = [];                                          % Array of axes handles for each plane in the current trial
        currTrialInd = [];                                          % Index of the currently selected trial num
        cm = repmat([ rgb('Blue'); rgb('Green'); rgb('Red'); ...    % List of colors to cycle through for parent ROIs
            rgb('Cyan'); rgb('Purple'); rgb('Brown'); ...
            rgb('Indigo'); rgb('DarkRed') ; rgb('Magenta') ; ...
            rgb('Gold')], 50, 1);
        currColorInd = 1;                                           % Keeps track of which color is up next
        roiData = [];                                               % Holds information about the current list of ROIs
        
    end
    
    methods (Access = private)
        
        % ----------------------- Clear everything that would change between trials ----------------------
        function clear_trial_data(app)
            
            app.currTrialInd = [];
            app.planesListBox.Items = {};
            
            clear_plot_data(app)
            
        end
        
        % ------------------------------------ Clear plotting panel -------------------------------------
        function clear_plot_data(app)
            
            % Hide all axes and plots
            if ~isempty(app.refImageAxes)
                for iPlot = 1:numel(app.refImageAxes)
                    app.refImageAxes{iPlot}.Visible = 'off';
                    app.refImageAxes{iPlot}.Title.Visible = 'off';
                    for iChild = 1:numel(app.refImageAxes{iPlot}.Children)
                        app.refImageAxes{iPlot}.Children(iChild).Visible = 'off';
                    end
                end
            end
            app.planeSelectDropdown.Items = {};
            
        end
        
        % ------------------------------- Update the reference image plots -------------------------------
        function update_display(app)
            
            % Clear plotting panel
            clear_plot_data(app)
            
            % Get the numbers of the currently selected planes
            planeNums = strip(app.planesListBox.Value);
            
            % Calculate the necessary subplot dimensions
            nPlanes = numel(planeNums);
            subplotDim1 = ceil(sqrt(nPlanes));
            subplotDim2 = floor(sqrt(nPlanes));
            if (subplotDim1 * subplotDim2) < nPlanes
                subplotDim2 = ceil(sqrt(nPlanes));
            end
            padSize = 0.01;
            rightMargin = 0;
            axWidth = (1 - rightMargin - ((subplotDim1 + 1) * padSize)) / subplotDim1;
            axHeight = (1 - ((subplotDim2 + 1) * padSize)) / subplotDim2;
            
            % Create axes in correct locations
            roiRefImgAxes = [];
            for yDim = 1:subplotDim2
                for xDim = 1:subplotDim1
                    xPos = (xDim * padSize) + ((xDim-1) * axWidth);
                    yPos = (yDim * padSize) + ((yDim-1) * axHeight);
                    roiRefImgAxes{yDim, xDim} = axes(app.plotPanel, ...
                        'Units', 'Normalized', ...
                        'Position', [xPos, yPos, axWidth, axHeight]);
                end
            end
            
            % Order handles by z-planes, displayed from L-R, top-bottom
            roiRefImgAxes = reshape(flipud(roiRefImgAxes)', 1, numel(roiRefImgAxes));
            
            % Delete any extra (unused) axes
            if numel(roiRefImgAxes) > nPlanes
                for iAx = (nPlanes + 1):numel(roiRefImgAxes)
                    delete(roiRefImgAxes{nPlanes + 1})
                end
                roiRefImgAxes = roiRefImgAxes(1:nPlanes);
            end
            
            % Plot reference frames for each plane
            for iPlane = 1:nPlanes
                currAxes = roiRefImgAxes{iPlane};
                axes(currAxes)
                axis(currAxes, 'image')
                hold(currAxes, 'on');
                maxIntensity = str2double(app.maxIntensityEditField.Value);
                imshow(app.refImages{app.currTrialInd}(:, :, iPlane), [0 maxIntensity], ...
                    'InitialMagnification', 'fit', 'Parent', currAxes);
                currAxes.Title.String = ['Plane #' planeNums{iPlane}];
                currAxes.UserData = planeNums{iPlane};
                currAxes.Toolbar.Visible = 'off';
                currAxes.Interactions = [];
                axis(currAxes, 'image');
            end
            
            % Check ROI listbox to see if any existing ROIs need to be transferred to the new axes
            if ~isempty(app.ROIsListBox.Value)
                
                % Loop through parent ROIs
                for iROI = 1:numel(app.ROIsListBox.Items)
                    currROIName = app.ROIsListBox.Items{iROI};
                    currROIData = app.roiData(iROI);
                    
                    % Loop through each subROI
                    for iSubROI = 1:numel(currROIData.subROIs)
                        currPlane = currROIData.subROIs(iSubROI).plane;
                        [~, currPlaneInd] = ismember(num2str(currPlane), planeNums);
                        
                        if currPlaneInd
                            
                            % Transfer subROI over to the new parent axes
                            currROIData.subROIs(iSubROI).obj.Parent = roiRefImgAxes{currPlaneInd};
                            
                            % Adjust visibility of subROIs according to ROIsListBox selection status
                            if ismember(currROIName, app.ROIsListBox.Value)
                                currROIData.subROIs(iSubROI).obj.Visible = 'on';
                            else
                                currROIData.subROIs(iSubROI).obj.Visible = 'off';
                            end
                            
                        end
                    end%iSubROI
                end%iROI
            end%if
            
            % Update stored axes handles
            oldAxes = app.refImageAxes(str2double(planeNums));
            app.refImageAxes(str2double(planeNums)) = roiRefImgAxes;
            for iAx = 1:numel(oldAxes)
                delete(oldAxes{iAx})
            end
            
            % (Re)populate the plane select dropdown
            for iPlane = 1:nPlanes
                app.planeSelectDropdown.Items{iPlane} = ['Plane ', planeNums{iPlane}];
            end
            if numel(app.planeSelectDropdown.Items) > 0
                app.planeSelectDropdown.Value = app.planeSelectDropdown.Items{1};
            end
            
        end%function
        
    end%methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Prompt user for experiment directory
            expDir = uigetdir(app.defaultExpDir, 'Select a directory containing reference image files');
            if ~expDir
                expDir = '';
            end
            app.expDirEditField.Value = expDir; %#ok<*ADPROPLC> 
%             app.expDirEditField.Value = 'D:\Dropbox (HMS)\2P Data\Imaging Data\20191113-1_38A11-ChR-6m\ProcessedData';
            
        end

        % Button pushed function: loadRefImgButton
        function loadRefImgButtonPushed(app, event)
            
            % Determine whether to look for raw or registered files
            imgType = app.imgTypeSwitch.Value;
            if contains(imgType, 'Raw')
                imgType = 'raw';
            else
                imgType = 'reg';
            end
            
            % Attempt to find reference image files in the experiment directory
            app.expDir = app.expDirEditField.Value;
            refImgFiles = dir(fullfile(app.expDir, ['refImages_', imgType, '_trial*.mat']));
            if numel(refImgFiles) == 0
                uialert(app.UIFigure, 'Error: could not find any reference image files of that type in the selected directory')
            else
                
                % Clear any existing data
                app.trialNums = [];
                app.refImages = [];
                clear_trial_data(app);
                
                % Extract trial numbers and compile images from each file
                for iFile = 1:numel(refImgFiles)
                    load(fullfile(app.expDir, refImgFiles(iFile).name), 'refImages') % refImages: [y, x, plane]
                    trialNum = str2double(regexp(refImgFiles(iFile).name, '(?<=trial_)...(?=.mat)', 'match', 'once'));
                    app.trialNums(iFile) = trialNum;
                    app.refImages{iFile} = refImages; 
                end
                app.msgLabel.Text = [' Loaded ', num2str(numel(refImgFiles)), ' reference image files'];
            end
            
            % Set trial number to first trial and then trigger the spinner's callback
            app.trialNumSpinner.Value = app.trialNums(1);
            trialNumSpinnerValueChanged(app);
            
        end

        % Value changed function: trialNumSpinner
        function trialNumSpinnerValueChanged(app, event)
            
            % Clear data from the previously selected trial
            clear_trial_data(app)
            
            % Determine whether new value is one of the loaded data's trial numbers
            if sum(app.trialNums == app.trialNumSpinner.Value)
                
                % Get index of the current trial
                app.currTrialInd = find(app.trialNums == app.trialNumSpinner.Value);
                
                % Count number of planes for current trial and add them to planes list
                nPlanes = size(app.refImages{app.currTrialInd}, 3);
                
                % Populate the plane select listBox
                for iPlane = 1:nPlanes
                    app.planesListBox.Items{iPlane} = pad(num2str(iPlane), 5, 'left');
                end
                app.planesListBox.Value = app.planesListBox.Items; % Select all planes by default
                
                % Create temporary placeholder axes if necessary
                nAxes = numel(app.refImageAxes);
                if nAxes < nPlanes
                    for iAx = (nAxes + 1):nPlanes
                        app.refImageAxes{iAx} = axes(app.plotPanel, 'Visible', 'off');
                    end
                end
                
                % Update the display
                update_display(app);
                
            else
                % Leave everything blank if there's no data for that trial
                app.msgLabel.Text = ' Warning: no reference image was found for this trial number';
            end
            
        end

        % Button pushed function: createROIButton
        function createROIButtonPushed(app, event)
            
            % Ask user to enter a name for the ROI
            opts.Interpreter = 'tex';
            roiName = inputdlg('\fontsize{12}Please enter a name for the new ROI:', '', 1 , {''}, opts);
            
            % Make sure they entered a valid name
            currROIs = app.ROIsListBox.Items;
            if isempty(roiName)
                app.msgLabel.Text = ' ROI creation cancelled';
            elseif strcmpi(roiName{1}, '') || (~isempty(currROIs) && any(cellfun(@strcmp, currROIs, repmat(roiName, size(currROIs)))))
                uialert(app.UIFigure, ...
                    "Error: please enter a valid ROI name. Cannot be an empty string or a duplicate of an existing ROI's name.", 'Error')
            else
                % Add a new entry to the ROIs listbox
                app.roiData(end + 1).name = roiName{:};
                app.roiData(end).subROIs = struct('obj', [], 'plane', [], 'refImage', []);
                app.roiData(end).Color = app.cm(app.currColorInd, :);
                app.currColorInd = app.currColorInd + 1;
                app.ROIsListBox.Items{end + 1} = app.roiData(end).name;
            end
            
            
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            
            selectedItems = app.ROIsListBox.Value;
            
            if numel(selectedItems) > 1 || isempty(selectedItems)
                app.selectedROIEditField.Value = '[select an ROI from the list]';
            else
                selectedItems = selectedItems{:};
                app.selectedROIEditField.Value = selectedItems;
            end
            
        end

        % Button pushed function: deleteROIButton
        function deleteROIButtonPushed(app, event)
            
            selectedItems = app.ROIsListBox.Value;
            if ~isempty(selectedItems)
                selection = uiconfirm(app.UIFigure, 'Are you sure you want to delete the selected ROIs?', ...
                    'Confirm deletion');
                if strcmpi(selection, 'OK')
                    
                    % Make a list of the names of ROIs to be deleted
                    deleteROINames = {};
                    for iROI = 1:numel(selectedItems)
                        deleteROINames{iROI} = selectedItems{iROI};
                    end
                    
                    % Delete the ROIs in the listBox items and in the roiData property
                    delItems = ismember(deleteROINames, app.ROIsListBox.Items);
                    app.ROIsListBox.Items(delItems) = [];
                    app.roiData(delItems) = [];
                    app.msgLabel.text = [' Deleted ', num2str(numel(delItems)), ' ROIs'];
                    
                    % Reset the selected ROI edit field
                    app.selectedROIEditField.Value = '[select an ROI from the list]';
                    
                    % Update the display in the plot panel
                    update_display(app)
                else
                    app.msgLabel.text = ' ROI deletion cancelled';
                end
            end
            
        end

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
            
            % Check plane and ROI list selection status
            targetPlaneStr = app.planeSelectDropdown.Value;
            targetPlane = regexp(targetPlaneStr, '(?<=Plane ).*', 'match', 'once');
            
            % Get parent ROI info
            parentROIIndex = strcmp(app.ROIsListBox.Items, app.selectedROIEditField.Value);
            parentROI = app.roiData(parentROIIndex);
            
            if isnumeric(str2double(targetPlane)) && ~isempty(parentROI) && ~strcmpi(parentROI.name, '[select an ROI from the list]')
                
                % Grab handle to the appropriate axes
                for iPlot = 1:numel(app.refImageAxes)
                    if strcmp(app.refImageAxes{iPlot}.UserData, targetPlane)
                        targetAxes = app.refImageAxes{iPlot};
                    end
                end
                
                % Prompt user to draw the new ROI
                titleStr = targetAxes.Title.String;
                targetAxes.Title.String = '*** DRAW AN ROI ***';
                newROI = images.roi.Freehand(targetAxes, 'Color', parentROI.Color, 'FaceAlpha', 0.05);
                newROI.draw();
                newROI.Label = parentROI.name;
                targetAxes.Title.String = titleStr;
                
                % Save information about new ROI
                if isempty(app.roiData(parentROIIndex).subROIs(1).obj)
                    app.roiData(parentROIIndex).subROIs(1).obj = newROI;
                else
                    app.roiData(parentROIIndex).subROIs(end + 1).obj = newROI;
                end
                app.roiData(parentROIIndex).subROIs(end).plane = str2double(targetPlane);
            else
                uialert(app.UIFigure, 'Error: please make sure you have selected a valid plane and parent ROI!', 'Error')
            end
        end

        % Button pushed function: updateDisplayButton
        function updateDisplayButtonPushed(app, event)
            update_display(app)
        end

        % Button pushed function: saveROIsButton
        function saveROIsButtonPushed(app, event)
            
            
            % ----------- CHECK FOR ERRORS ------------
            proceed = 1;
            
            % Make sure there is at least one selected ROI in the list
            if isempty(app.ROIsListBox.Value)
                proceed = 0;
                uialert(app.UIFigure, 'Error: no ROIs are selected in the ROI list!', 'Error');
                app.msgLabel.Text = 'Saving cancelled';
            else
                
                % Make sure each selected ROI has at least one subROI
                for iROI = 1:numel(app.ROIsListBox.Value)
                    currROIData = app.roiData(strcmp(app.ROIsListBox.Value{iROI}, {app.roiData.name}));
                    if isempty(currROIData.subROIs(1).obj)
                        proceed = 0;
                        uialert(app.UIFigure, ['Error: parent ROI "', currROIData.name, ...
                            '" does not have any subROIs so it cannot be saved!'], 'Error');
                        app.msgLabel.Text = 'Saving cancelled';
                        break
                    else
                        
                        % Make sure no sub-ROI has a plane number greater than the current trial's nPlanes
                        for iSubROI = 1:numel(currROIData.subROIs)
                            if currROIData.subROIs(iSubROI).plane > numel(app.planesListBox.Items)
                                proceed = 0;
                                uialert(app.UIFigure, ['Error: at least one sub-ROI of parent ROI "', currROIData.name, ...
                                    '" has a plane number greater than the number of planes in reference images for the ', ...
                                    'current trial!'], 'Error');
                                app.msgLabel.Text = 'Saving cancelled';
                                break
                            end
                        end%iSubROI
                        if ~proceed
                            break % Exit the outer loop as well if the inner one found an error
                        end
                    end%if
                end%iROI
                
                % Warn user if there are unselected ROIs in the listBox
                if numel(app.ROIsListBox.Value) ~= numel(app.ROIsListBox.Items)
                    selection = uiconfirm(app.UIFigure, ['There is at least one unselected ROI in the list that will not be saved', ...
                        '...are you sure you want to proceed?'], 'Confirm save');
                    if ~strcmpi(selection, 'OK')
                        proceed = 0;
                        app.msgLabel.Text = 'Saving cancelled';
                    end
                end
                
                % Warn user if there are any sub-ROIs from selected ROIs that are not currently shown on the plots
                for iROI = 1:numel(app.ROIsListBox.Value)
                    currROIData = app.roiData(strcmp(app.ROIsListBox.Value{iROI}, {app.roiData.name}));
                    for iSubROI = 1:numel(currROIData.subROIs)
                        if strcmpi(currROIData.subROIs(iSubROI).obj.Visible, 'off')
                            selection = uiconfirm(app.UIFigure, ['Warning: at least one sub-ROI of parent ROI "', currROIData.name, ...
                                '" is not currently displayed in the reference image plots, but it will still be included in the saved ', ...
                                'ROI data if you continue. Are you sure you want to proceed?'], 'Confirm save');
                            if ~strcmpi(selection, 'OK')
                                proceed = 0;
                                app.msgLabel.Text = 'Saving cancelled';
                            end
                            break
                        end
                    end%iSubROI
                end%iROI
            end%if
            
            % Prompt user for a file name
            if proceed
                opts.Interpreter = 'tex';
                fileNamePrefix = inputdlg('\fontsize{12}Please enter a prefix for the save file name:', '', 1 , {'roiDefs'}, opts);
                
                % Warn user if saving will overwrite an existing file
                saveFile = fullfile(app.expDir, [fileNamePrefix{:}, '_trial_', pad(num2str(app.trialNumSpinner.Value), 3, 'left', '0'), '.mat']);
                if exist(saveFile, 'file')
                    selection = uiconfirm(app.UIFigure, ['There is an existing file with that name that will be overwritten by this ', ...
                        'save...are you sure you want to proceed?'], 'Confirm file overwrite');
                    if ~strcmpi(selection, 'OK')
                        proceed = 0;
                        app.msgLabel.Text = 'Saving cancelled';
                    end
                end
            end
            
            
            % ----------- SAVE ROI DATA ------------
            
            if proceed
                
                % Process data for each ROI
                selectedROIData = app.roiData(ismember(app.ROIsListBox.Value, {app.roiData.name}));
                for iROI = 1:numel(selectedROIData)
                    for iSubROI = 1:numel(selectedROIData(iROI).subROIs)
                        
                        currSubROIObj = selectedROIData(iROI).subROIs(iSubROI).obj;
                        
                        % Link a reference image to each sub-ROI
                        planeNum = selectedROIData(iROI).subROIs(iSubROI).plane;
                        selectedROIData(iROI).subROIs(iSubROI).refImage = app.refImages{app.currTrialInd}(:, :, planeNum);
                        
                        % Extract properties from ROI object 
                        selectedROIData(iROI).subROIs(iSubROI).position = currSubROIObj.Position;
                        selectedROIData(iROI).subROIs(iSubROI).mask = currSubROIObj.createMask();
                        
                    end%iSubROI
                end%iROI
                
                % Save ROI data
                roiDefs = selectedROIData;
                save(saveFile, 'roiDefs', '-v7.3');                
                app.msgLabel.Text = ['ROIs saved for trial #', num2str(app.trialNumSpinner.Value)];
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ROI creation GUI';
            app.ExperimentdirectoryLabel = uilabel(app.UIFigure);
            app.ExperimentdirectoryLabel.Text = 'Experiment directory';
            app.expDirEditField = uieditfield(app.UIFigure, 'text');
            app.expDirEditField.Editable = 'off';
            app.imgTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.imgTypeSwitch.Items = {'Raw images', 'Registered'};
            app.imgTypeSwitch.Value = 'Raw images';
            app.loadRefImgButton = uibutton(app.UIFigure, 'push');
            app.loadRefImgButton.ButtonPushedFcn = createCallbackFcn(app, @loadRefImgButtonPushed, true);
            app.loadRefImgButton.Text = 'Load reference images';
            app.TrialnumberLabel = uilabel(app.UIFigure);
            app.TrialnumberLabel.Text = 'Trial number';
            app.trialNumSpinner = uispinner(app.UIFigure);
            app.trialNumSpinner.Limits = [1 Inf];
            app.trialNumSpinner.RoundFractionalValues = 'on';
            app.trialNumSpinner.ValueChangedFcn = createCallbackFcn(app, @trialNumSpinnerValueChanged, true);
            app.trialNumSpinner.Value = 1;
            app.MaxintensityLabel = uilabel(app.UIFigure);
            app.MaxintensityLabel.Text = 'Max intensity';
            app.maxIntensityEditField = uieditfield(app.UIFigure, 'text');
            app.maxIntensityEditField.Value = '700';
            app.ShowplanesLabel = uilabel(app.UIFigure);
            app.ShowplanesLabel.Text = 'Show planes:';
            app.planesListBox = uilistbox(app.UIFigure);
            app.planesListBox.Items = {};
            app.planesListBox.Multiselect = 'on';
            app.planesListBox.Value = {};
            app.updateDisplayButton = uibutton(app.UIFigure, 'push');
            app.updateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @updateDisplayButtonPushed, true);
            app.updateDisplayButton.Text = 'Update plots';
            app.drawButton = uibutton(app.UIFigure, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'Draw a new sub-ROI';
            app.onLabel = uilabel(app.UIFigure);
            app.onLabel.Text = 'on';
            app.planeSelectDropdown = uidropdown(app.UIFigure);
            app.planeSelectDropdown.Items = {'Plane'};
            app.planeSelectDropdown.Value = 'Plane';
            app.andaddittoLabel = uilabel(app.UIFigure);
            app.andaddittoLabel.Text = 'and add it to';
            app.selectedROIEditField = uieditfield(app.UIFigure, 'text');
            app.selectedROIEditField.Value = '[select an ROI from the list]';
            app.createROIButton = uibutton(app.UIFigure, 'push');
            app.createROIButton.ButtonPushedFcn = createCallbackFcn(app, @createROIButtonPushed, true);
            app.createROIButton.Text = 'Create new ROI';
            app.deleteROIButton = uibutton(app.UIFigure, 'push');
            app.deleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @deleteROIButtonPushed, true);
            app.deleteROIButton.Text = 'Delete selected ROIs';
            app.saveROIsButton = uibutton(app.UIFigure, 'push');
            app.saveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @saveROIsButtonPushed, true);
            app.saveROIsButton.Text = {'Save selected ROIs'; ' for current trial'};
            app.plotPanel = uipanel(app.UIFigure);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ROIsLabel = uilabel(app.UIFigure);
            app.ROIsLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.UIFigure);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.Multiselect = 'on';
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {};
            app.msgLabel = uilabel(app.UIFigure);
            app.msgLabel.Text = '  ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = panels_ROI_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end